classdef Epc660CaptureSoftware < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PreviewSettingsPanel        matlab.ui.container.Panel
        PreviewButton               matlab.ui.control.Button
        ModSpeedLabel               matlab.ui.control.Label
        ModSpeedDropDown            matlab.ui.control.DropDown
        PreviewTypeLabel            matlab.ui.control.Label
        PreviewTypeDropDown         matlab.ui.control.DropDown
        FrameAveragingCheckBox      matlab.ui.control.CheckBox
        FPNRemovalCheckBox          matlab.ui.control.CheckBox
        PauseButton                 matlab.ui.control.Button
        DistanceOffsetLabel         matlab.ui.control.Label
        DistanceOffsetEditField     matlab.ui.control.NumericEditField
        IntegrationTimeLabel        matlab.ui.control.Label
        IntegrationTimeEditField    matlab.ui.control.NumericEditField
        AvgSamplesLabel             matlab.ui.control.Label
        AvgSamplesEditField         matlab.ui.control.NumericEditField
        MinEditFieldLabel           matlab.ui.control.Label
        PreviewMinEditField         matlab.ui.control.NumericEditField
        MaxEditFieldLabel           matlab.ui.control.Label
        PreviewMaxEditField         matlab.ui.control.NumericEditField
        EnableIlluminationCheckBox  matlab.ui.control.CheckBox
        SavePanel                   matlab.ui.container.Panel
        CaptureButton               matlab.ui.control.Button
        FastModeCheckBox            matlab.ui.control.CheckBox
        SamplesLabel                matlab.ui.control.Label
        SamplesEditField            matlab.ui.control.NumericEditField
        IntTimesLabel               matlab.ui.control.Label
        IntTimesEditField           matlab.ui.control.NumericEditField
        ModSpeedsLabel              matlab.ui.control.Label
        ModSpeedsEditField          matlab.ui.control.NumericEditField
        PhasePanel                  matlab.ui.container.Panel
        HistogramScalePanel         matlab.ui.container.Panel
        MinLabel_3                  matlab.ui.control.Label
        AHistMinEditField           matlab.ui.control.NumericEditField
        MaxLabel_4                  matlab.ui.control.Label
        AHistMaxEditField           matlab.ui.control.NumericEditField
        MinLabel_4                  matlab.ui.control.Label
        RHistMinEditField           matlab.ui.control.NumericEditField
        MaxLabel_5                  matlab.ui.control.Label
        RHistMaxEditField           matlab.ui.control.NumericEditField
        PreviewPanel                matlab.ui.container.Panel
        AmplitudeHistogramPanel     matlab.ui.container.Panel
        RangeHistogramPanel         matlab.ui.container.Panel
        RScalePanel                 matlab.ui.container.Panel
        MinLabel                    matlab.ui.control.Label
        PAMinEditField              matlab.ui.control.NumericEditField
        MaxLabel_3                  matlab.ui.control.Label
        PAMaxEditField              matlab.ui.control.NumericEditField
        ThetaScalePanel             matlab.ui.container.Panel
        MinLabel_2                  matlab.ui.control.Label
        PhaseMinEditField           matlab.ui.control.NumericEditField
        MaxLabel_2                  matlab.ui.control.Label
        PhaseMaxEditField           matlab.ui.control.NumericEditField
        SendCommandEditFieldLabel   matlab.ui.control.Label
        SendCommandEditField        matlab.ui.control.EditField
        SendButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        CaptureDevice % the epc660 tof camera
        CurrentFrame % the currently displayed frame
        Dataset % the captured dataset for the 'Save' feature
        IsCapturing % boolean to determine capture state
        Transmissometer % the transmissometer for turbidity measurements
        SaveFilePath % the filepath for saving the dataset
        PreviewType % the image type for the preview window
    end
    
    properties (Access = public)
        PolarAxes % Description
        PreviewAxes
        AmplitudeHistAxes
        RangeHistAxes
        NHistBins = floor(((320*240)^(1/3))*2);
    end
    
    methods (Access = private)
        
        function updateUI(app, data)
            switch app.PreviewType
                case 'Amplitude'
                    img = data.Amplitude;
                case 'Range'
                    img = data.Range;
                case 'Phase'
                    img = data.Phase;
                case 'Grayscale'
                    img = data.Gray;
                case 'DCS'
                    img = data.DCS0;
            end
            imagesc(img, 'Parent', app.PreviewAxes)
            
%             drawnow
            polarplot(app.PolarAxes, data.Phase(:), data.Amplitude(:), '.')
%             drawnow
            
            histogram(app.AmplitudeHistAxes, data.Amplitude,'normalization','probability','NumBins',app.NHistBins)
            histogram(app.RangeHistAxes, data.Range, 'normalization', 'probability','NumBins',app.NHistBins)
            drawnow limitrate;
        end
        
        function connectEpc660(app)
            address = '192.168.7.2';
            port = 50660;
            app.CaptureDevice = Epc660(address, port);
        end
        
        function removeFPN(app, data)
            
        end
        
        function frameAverage(app, data)
            
        end
        
        function ModVal = getModVal(app)
           switch app.ModSpeedDropDown.Value
                case '24.0 MHz'
                    ModVal = 24e6;
                case '12.0 MHz'
                    ModVal = 12e6;
                case '6.0 MHz'
                    ModVal = 6e6;
                case '3.0 MHz'
                    ModVal = 3e6;
                case '1.5 MHz'
                    ModVal = 1.5e6;
                case '0.75 MHz'
                    ModVal = 0.75e6;
                case '0.375 MHz'
                    ModVal = 0.375e6;
            end
        end
        
       function ModNum = getModNum(app)
           switch app.ModSpeedDropDown.Value
                case '24.0 MHz'
                    ModNum = 0;
                case '12.0 MHz'
                    ModNum = 1;
                case '6.0 MHz'
                    ModNum = 2;
                case '3.0 MHz'
                    ModNum = 3;
                case '1.5 MHz'
                    ModNum = 4;
                case '0.75 MHz'
                    ModNum = 5;
                case '0.375 MHz'
                    ModNum = 6;
            end
       end
        
        
       function createPolarAxes(app)
            app.PolarAxes = polaraxes(app.PhasePanel);
            title(app.PolarAxes, 'Polar Coordinates')
            app.PolarAxes.Toolbar.Visible = 'off';
            app.PolarAxes.HitTest = 'off';
            app.PolarAxes.PickableParts = 'none';
            app.PolarAxes.NextPlot='replacechildren';
            app.PolarAxes.ThetaAxisUnits = 'radians';
            app.PolarAxes.ThetaLim = [0 2*pi];
            app.PolarAxes.RLim = [0 4096];
            app.PolarAxes.ThetaDir = 'counterclockwise';
            disableDefaultInteractivity(app.PolarAxes)
       end
       function createAmplitudeHistAxes(app)
            % Create AmplitudeHist
            app.AmplitudeHistAxes=axes('parent',app.AmplitudeHistogramPanel);
            ylabel(app.AmplitudeHistAxes, 'Counts')
            app.AmplitudeHistAxes.Toolbar.Visible = 'off';
            app.AmplitudeHistAxes.XLim = [0 4096];
            app.AmplitudeHistAxes.YLim = [0 0.5];
            app.AmplitudeHistAxes.HitTest = 'off';
            app.AmplitudeHistAxes.PickableParts = 'none';
            app.AmplitudeHistAxes.NextPlot='replacechildren';
            disableDefaultInteractivity(app.AmplitudeHistAxes)
       end
       
       function createRangeHistAxes(app)
            % Create RangeHist
            app.RangeHistAxes=axes('parent',app.RangeHistogramPanel);
            ylabel(app.RangeHistAxes, 'Counts')
            app.RangeHistAxes.Toolbar.Visible = 'off';
            app.RangeHistAxes.XLim = [0 10];
            app.RangeHistAxes.YLim = [0 0.5];
            app.RangeHistAxes.HitTest = 'off';
            app.RangeHistAxes.PickableParts = 'none';
            app.RangeHistAxes.NextPlot='replacechildren';
            disableDefaultInteractivity(app.RangeHistAxes)
       end
        
        function createPreviewAxes(app)
%             app.ImageAxes=figure;
            app.PreviewAxes=axes('parent',app.PreviewPanel); % ,'Position',[11 304 470 348]
            app.PreviewAxes.Interactions=[];
            app.PreviewAxes.Toolbar=[];
            disableDefaultInteractivity(app.PreviewAxes)
%             app.PreviewAxes.MenuBar=[];
            app.PreviewAxes.NextPlot='replacechildren';
            app.PreviewAxes.XLim = [1 321];
            app.PreviewAxes.YLim = [1 241];
            app.PreviewAxes.Colormap = gray(4096);
            app.PreviewAxes.CLim = [app.PreviewMinEditField.Value, app.PreviewMaxEditField.Value];
            colorbar(app.PreviewAxes);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            createPreviewAxes(app)
            createPolarAxes(app)
            createAmplitudeHistAxes(app)
            createRangeHistAxes(app)
            
%             axis(app.PreviewAxes, 'image');
            
            % attempt to connect to the camera
            connectEpc660(app);
            
            app.PreviewType = app.PreviewTypeDropDown.Value;
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            app.CaptureDevice.loadConfig(1);
            app.CaptureDevice.enableIllumination(0);
            app.CaptureDevice.startVideo();
            app.IsCapturing = true;
            app.CaptureDevice.setModulationFrequency(getModNum(app));
            while app.IsCapturing == true
                data=app.CaptureDevice.getDCSTOFAndGrayscaleSorted();
                data.Range = tofComputeDistance(data, getModVal(app), app.DistanceOffsetEditField.Value);
                data.Amplitude = tofComputeAmplitude(data);
                data.Phase = tofComputePhase(data, pi);
                updateUI(app, data);
                pause(0.001);
            end
            app.CaptureDevice.stopVideo();
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.IsCapturing = false;
        end

        % Value changed function: IntegrationTimeEditField
        function IntegrationTimeEditFieldValueChanged(app, event)
            value = app.IntegrationTimeEditField.Value;
            app.CaptureDevice.setIntegrationTime3D(value);
        end

        % Value changed function: PreviewTypeDropDown
        function PreviewTypeDropDownValueChanged(app, event)
            value = app.PreviewTypeDropDown.Value;
            app.PreviewAxes.CLim = ([app.PreviewMinEditField.Value app.PreviewMaxEditField.Value]);
            app.PreviewType = value;
        end

        % Value changed function: ModSpeedDropDown
        function ModSpeedDropDownValueChanged(app, event)
            app.CaptureDevice.setModulationFrequency(getModNum(app));
        end

        % Value changed function: PreviewMinEditField
        function PreviewMinEditFieldValueChanged(app, event)
            app.PreviewAxes.CLim = [app.PreviewMinEditField.Value, app.PreviewMaxEditField.Value];
        end

        % Value changed function: PreviewMaxEditField
        function PreviewMaxEditFieldValueChanged(app, event)
            app.PreviewAxes.CLim = [app.PreviewMinEditField.Value, app.PreviewMaxEditField.Value];
        end

        % Value changed function: AHistMinEditField
        function AHistMinEditFieldValueChanged(app, event)
            app.AmplitudeHistAxes.XLim = [app.AHistMinEditField.Value, app.AHistMaxEditField.Value];
        end

        % Value changed function: AHistMaxEditField
        function AHistMaxEditFieldValueChanged(app, event)
            app.AmplitudeHistAxes.XLim = [app.AHistMinEditField.Value, app.AHistMaxEditField.Value];
        end

        % Value changed function: RHistMinEditField
        function RHistMinEditFieldValueChanged(app, event)
            app.RangeHistAxes.XLim = [app.RHistMinEditField.Value, app.RHistMaxEditField.Value];
        end

        % Value changed function: RHistMaxEditField
        function RHistMaxEditFieldValueChanged(app, event)
            app.RangeHistAxes.XLim = [app.RHistMinEditField.Value, app.RHistMaxEditField.Value];
            
        end

        % Value changed function: PAMinEditField
        function PAMinEditFieldValueChanged(app, event)
            app.PolarAxes.RLim = [app.PAMinEditField.Value, app.PAMaxEditField.Value];
        end

        % Value changed function: PAMaxEditField
        function PAMaxEditFieldValueChanged(app, event)
            app.PolarAxes.RLim = [app.PAMinEditField.Value, app.PAMaxEditField.Value];
        end

        % Value changed function: PhaseMinEditField
        function PhaseMinEditFieldValueChanged(app, event)
             app.PolarAxes.ThetaLim = [app.PhaseMinEditField.Value*pi, app.PhaseMaxEditField.Value*pi];
        end

        % Value changed function: PhaseMaxEditField
        function PhaseMaxEditFieldValueChanged(app, event)
            app.PolarAxes.ThetaLim = [app.PhaseMinEditField.Value*pi, app.PhaseMaxEditField.Value*pi];
        end

        % Value changed function: EnableIlluminationCheckBox
        function EnableIlluminationCheckBoxValueChanged(app, event)
            app.CaptureDevice.enableIllumination(event.Value)
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            cmd = app.SendCommandEditField.Value;
            app.CaptureDevice.executeCommandOnServer(cmd)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'TOF Capture Software';
            app.PreviewSettingsPanel = uipanel(app.UIFigure);
            app.PreviewSettingsPanel.Title = 'Preview Settings';
            app.PreviewButton = uibutton(app.PreviewSettingsPanel, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.ModSpeedLabel = uilabel(app.PreviewSettingsPanel);
            app.ModSpeedLabel.Text = 'Mod. Speed:';
            app.ModSpeedDropDown = uidropdown(app.PreviewSettingsPanel);
            app.ModSpeedDropDown.Items = {'24.0 MHz', '12.0 MHz', '6.0 MHz', '3.0 MHz', '1.5 MHz', '0.75 MHz'};
            app.ModSpeedDropDown.ValueChangedFcn = createCallbackFcn(app, @ModSpeedDropDownValueChanged, true);
            app.ModSpeedDropDown.Value = '24.0 MHz';
            app.PreviewTypeLabel = uilabel(app.PreviewSettingsPanel);
            app.PreviewTypeLabel.Text = 'Preview Type:';
            app.PreviewTypeDropDown = uidropdown(app.PreviewSettingsPanel);
            app.PreviewTypeDropDown.Items = {'Amplitude', 'Range', 'Phase', 'DCS', 'Grayscale'};
            app.PreviewTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PreviewTypeDropDownValueChanged, true);
            app.PreviewTypeDropDown.Value = 'Amplitude';
            app.FrameAveragingCheckBox = uicheckbox(app.PreviewSettingsPanel);
            app.FrameAveragingCheckBox.Text = 'Frame Averaging';
            app.FPNRemovalCheckBox = uicheckbox(app.PreviewSettingsPanel);
            app.FPNRemovalCheckBox.Text = 'FPN Removal';
            app.PauseButton = uibutton(app.PreviewSettingsPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.DistanceOffsetLabel = uilabel(app.PreviewSettingsPanel);
            app.DistanceOffsetLabel.Text = 'Distance Offset:';
            app.DistanceOffsetEditField = uieditfield(app.PreviewSettingsPanel, 'numeric');
            app.IntegrationTimeLabel = uilabel(app.PreviewSettingsPanel);
            app.IntegrationTimeLabel.Text = 'Integration Time:';
            app.IntegrationTimeEditField = uieditfield(app.PreviewSettingsPanel, 'numeric');
            app.IntegrationTimeEditField.Limits = [0 20000];
            app.IntegrationTimeEditField.ValueChangedFcn = createCallbackFcn(app, @IntegrationTimeEditFieldValueChanged, true);
            app.IntegrationTimeEditField.Value = 100;
            app.AvgSamplesLabel = uilabel(app.PreviewSettingsPanel);
            app.AvgSamplesLabel.Text = 'Avg. Samples:';
            app.AvgSamplesEditField = uieditfield(app.PreviewSettingsPanel, 'numeric');
            app.MinEditFieldLabel = uilabel(app.PreviewSettingsPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.PreviewMinEditField = uieditfield(app.PreviewSettingsPanel, 'numeric');
            app.PreviewMinEditField.ValueChangedFcn = createCallbackFcn(app, @PreviewMinEditFieldValueChanged, true);
            app.MaxEditFieldLabel = uilabel(app.PreviewSettingsPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.PreviewMaxEditField = uieditfield(app.PreviewSettingsPanel, 'numeric');
            app.PreviewMaxEditField.Limits = [0 20000];
            app.PreviewMaxEditField.ValueChangedFcn = createCallbackFcn(app, @PreviewMaxEditFieldValueChanged, true);
            app.PreviewMaxEditField.Value = 4096;
            app.EnableIlluminationCheckBox = uicheckbox(app.PreviewSettingsPanel);
            app.EnableIlluminationCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableIlluminationCheckBoxValueChanged, true);
            app.EnableIlluminationCheckBox.Text = 'Enable Illumination';
            app.SavePanel = uipanel(app.UIFigure);
            app.SavePanel.Title = 'Save';
            app.CaptureButton = uibutton(app.SavePanel, 'push');
            app.CaptureButton.Text = 'Capture';
            app.FastModeCheckBox = uicheckbox(app.SavePanel);
            app.FastModeCheckBox.Text = 'Fast Mode';
            app.SamplesLabel = uilabel(app.SavePanel);
            app.SamplesLabel.Text = 'Samples:';
            app.SamplesEditField = uieditfield(app.SavePanel, 'numeric');
            app.IntTimesLabel = uilabel(app.SavePanel);
            app.IntTimesLabel.Text = 'Int. Time(s):';
            app.IntTimesEditField = uieditfield(app.SavePanel, 'numeric');
            app.ModSpeedsLabel = uilabel(app.SavePanel);
            app.ModSpeedsLabel.Text = 'Mod. Speed(s):';
            app.ModSpeedsEditField = uieditfield(app.SavePanel, 'numeric');
            app.PhasePanel = uipanel(app.UIFigure);
            app.PhasePanel.Title = 'Phase';
            app.HistogramScalePanel = uipanel(app.UIFigure);
            app.HistogramScalePanel.Title = 'Histogram Scale';
            app.MinLabel_3 = uilabel(app.HistogramScalePanel);
            app.MinLabel_3.Text = 'Min:';
            app.AHistMinEditField = uieditfield(app.HistogramScalePanel, 'numeric');
            app.AHistMinEditField.ValueChangedFcn = createCallbackFcn(app, @AHistMinEditFieldValueChanged, true);
            app.MaxLabel_4 = uilabel(app.HistogramScalePanel);
            app.MaxLabel_4.Text = 'Max:';
            app.AHistMaxEditField = uieditfield(app.HistogramScalePanel, 'numeric');
            app.AHistMaxEditField.Limits = [0 4096];
            app.AHistMaxEditField.ValueChangedFcn = createCallbackFcn(app, @AHistMaxEditFieldValueChanged, true);
            app.AHistMaxEditField.Value = 4096;
            app.MinLabel_4 = uilabel(app.HistogramScalePanel);
            app.MinLabel_4.Text = 'Min:';
            app.RHistMinEditField = uieditfield(app.HistogramScalePanel, 'numeric');
            app.RHistMinEditField.ValueChangedFcn = createCallbackFcn(app, @RHistMinEditFieldValueChanged, true);
            app.MaxLabel_5 = uilabel(app.HistogramScalePanel);
            app.MaxLabel_5.Text = 'Max:';
            app.RHistMaxEditField = uieditfield(app.HistogramScalePanel, 'numeric');
            app.RHistMaxEditField.ValueChangedFcn = createCallbackFcn(app, @RHistMaxEditFieldValueChanged, true);
            app.RHistMaxEditField.Value = 10;
            app.PreviewPanel = uipanel(app.UIFigure);
            app.PreviewPanel.Title = 'Preview';
            app.AmplitudeHistogramPanel = uipanel(app.UIFigure);
            app.AmplitudeHistogramPanel.Title = 'Amplitude Histogram';
            app.RangeHistogramPanel = uipanel(app.UIFigure);
            app.RangeHistogramPanel.Title = 'Range Histogram';
            app.RScalePanel = uipanel(app.UIFigure);
            app.RScalePanel.Title = 'R Scale';
            app.MinLabel = uilabel(app.RScalePanel);
            app.MinLabel.Text = 'Min:';
            app.PAMinEditField = uieditfield(app.RScalePanel, 'numeric');
            app.PAMinEditField.ValueChangedFcn = createCallbackFcn(app, @PAMinEditFieldValueChanged, true);
            app.MaxLabel_3 = uilabel(app.RScalePanel);
            app.MaxLabel_3.Text = 'Max:';
            app.PAMaxEditField = uieditfield(app.RScalePanel, 'numeric');
            app.PAMaxEditField.Limits = [0 4096];
            app.PAMaxEditField.ValueChangedFcn = createCallbackFcn(app, @PAMaxEditFieldValueChanged, true);
            app.PAMaxEditField.Value = 4096;
            app.ThetaScalePanel = uipanel(app.UIFigure);
            app.ThetaScalePanel.Title = 'Theta Scale';
            app.MinLabel_2 = uilabel(app.ThetaScalePanel);
            app.MinLabel_2.Text = 'Min:';
            app.PhaseMinEditField = uieditfield(app.ThetaScalePanel, 'numeric');
            app.PhaseMinEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseMinEditFieldValueChanged, true);
            app.MaxLabel_2 = uilabel(app.ThetaScalePanel);
            app.MaxLabel_2.Text = 'Max:';
            app.PhaseMaxEditField = uieditfield(app.ThetaScalePanel, 'numeric');
            app.PhaseMaxEditField.Limits = [0 2];
            app.PhaseMaxEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseMaxEditFieldValueChanged, true);
            app.PhaseMaxEditField.Value = 2;
            app.SendCommandEditFieldLabel = uilabel(app.UIFigure);
            app.SendCommandEditFieldLabel.Text = 'Send Command:';
            app.SendCommandEditField = uieditfield(app.UIFigure, 'text');
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Epc660CaptureSoftware

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end