classdef TankTrackSoftware < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TankTrackUIFigure             matlab.ui.Figure
        TrackControlPanel             matlab.ui.container.Panel
        ConnectButton                 matlab.ui.control.Button
        SendHomeButton                matlab.ui.control.Button
        SendToBackButton              matlab.ui.control.Button
        StepSizemLabel                matlab.ui.control.Label
        StepSizemEditField            matlab.ui.control.NumericEditField
        MoveForwardByStepSizeButton   matlab.ui.control.Button
        MoveBackByStepSizeButton      matlab.ui.control.Button
        StandoffDistancemGaugeLabel   matlab.ui.control.Label
        StandoffDistancemGauge        matlab.ui.control.LinearGauge
        Lamp                          matlab.ui.control.Lamp
        COMMPortNumberEditFieldLabel  matlab.ui.control.Label
        COMMPortNumberEditField       matlab.ui.control.NumericEditField
        UpdateTrackPositionButton     matlab.ui.control.Button
    end


    properties (Access = private)
        StepSize = 6.35e-6; % Tank Track step size
        TargetTrack
    end

    methods (Access = private)
        function UpdateTrackPositionLoop(app)
            for i = 1:140
                UpdateTrackPosition(app);
                pause(1);
            end
        end
        
        function UpdateTrackPosition(app)
            q = QueryCommTrack(app.TargetTrack);
            position = q.position * app.StepSize;
            app.StandoffDistancemGauge.Value = position;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create timer object
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.TargetTrack = InitializeCommTrack(app.COMMPortNumberEditField.Value);
            app.Lamp.Color = 'green';
            UpdateTrackPosition(app);
        end

        % Button pushed function: SendHomeButton
        function SendHomeButtonPushed(app, event)
            SendTrackHome(app.TargetTrack);
%             UpdateTrackPositionLoop(app);
        end

        % Button pushed function: SendToBackButton
        function SendToBackButtonPushed(app, event)
            MoveTrack(app.TargetTrack, int64(9.0/app.StepSize), 'rel');
%             UpdateTrackPositionLoop(app);
        end

        % Button pushed function: MoveForwardByStepSizeButton
        function MoveForwardByStepSizeButtonPushed(app, event)
            MoveTrack(app.TargetTrack, int64(app.StepSizemEditField.Value/app.StepSize), 'rel');
%             UpdateTrackPositionLoop(app);
        end

        % Button pushed function: MoveBackByStepSizeButton
        function MoveBackByStepSizeButtonPushed(app, event)
            MoveTrack(app.TargetTrack, int64(-app.StepSizemEditField.Value/app.StepSize), 'rel');
%             UpdateTrackPositionLoop(app);
        end

        % Button pushed function: UpdateTrackPositionButton
        function UpdateTrackPositionButtonPushed(app, event)
            UpdateTrackPosition(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TankTrackUIFigure = uifigure('Visible', 'off');
            app.TankTrackUIFigure.Name = 'Target Track';
            app.TrackControlPanel = uipanel(app.TankTrackUIFigure);
            app.TrackControlPanel.Title = 'Track Control Panel';
            app.ConnectButton = uibutton(app.TrackControlPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.SendHomeButton = uibutton(app.TrackControlPanel, 'push');
            app.SendHomeButton.ButtonPushedFcn = createCallbackFcn(app, @SendHomeButtonPushed, true);
            app.SendHomeButton.Text = 'Send Home';
            app.SendToBackButton = uibutton(app.TrackControlPanel, 'push');
            app.SendToBackButton.ButtonPushedFcn = createCallbackFcn(app, @SendToBackButtonPushed, true);
            app.SendToBackButton.Text = 'Send To Back';
            app.StepSizemLabel = uilabel(app.TrackControlPanel);
            app.StepSizemLabel.Text = 'Step Size [m]:';
            app.StepSizemEditField = uieditfield(app.TrackControlPanel, 'numeric');
            app.MoveForwardByStepSizeButton = uibutton(app.TrackControlPanel, 'push');
            app.MoveForwardByStepSizeButton.ButtonPushedFcn = createCallbackFcn(app, @MoveForwardByStepSizeButtonPushed, true);
            app.MoveForwardByStepSizeButton.Text = 'Move Forward By Step Size';
            app.MoveBackByStepSizeButton = uibutton(app.TrackControlPanel, 'push');
            app.MoveBackByStepSizeButton.ButtonPushedFcn = createCallbackFcn(app, @MoveBackByStepSizeButtonPushed, true);
            app.MoveBackByStepSizeButton.Text = 'Move Back By Step Size';
            app.StandoffDistancemGaugeLabel = uilabel(app.TrackControlPanel);
            app.StandoffDistancemGaugeLabel.Text = 'Standoff Distance [m]';
            app.StandoffDistancemGauge = uigauge(app.TrackControlPanel, 'linear');
            app.StandoffDistancemGauge.Limits = [0 8];
            app.StandoffDistancemGauge.MajorTicks = [0 1 2 3 4 5 6 7 8];
            app.StandoffDistancemGauge.MinorTicks = [0 0.25 0.5 0.75 1.25 1.5 1.75 2.25 2.5 2.75 3.25 3.5 3.75 4.25 4.5 4.75 5.25 5.5 5.75 6.25 6.5 6.75 7.25 7.5 7.75];
            app.Lamp = uilamp(app.TrackControlPanel);
            app.COMMPortNumberEditFieldLabel = uilabel(app.TrackControlPanel);
            app.COMMPortNumberEditFieldLabel.Text = 'COMM Port Number:';
            app.COMMPortNumberEditField = uieditfield(app.TrackControlPanel, 'numeric');
            app.UpdateTrackPositionButton = uibutton(app.TrackControlPanel, 'push');
            app.UpdateTrackPositionButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTrackPositionButtonPushed, true);
            app.UpdateTrackPositionButton.Text = 'Update Track Position';
            % Show the figure after all components are created
            app.TankTrackUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TankTrackSoftware

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TankTrackUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TankTrackUIFigure)
        end
    end
end